classdef GUI_Det_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                      matlab.ui.Figure
        panel                        matlab.ui.container.Panel
        pushbutton_Calcular          matlab.ui.control.Button
        TextArea                     matlab.ui.control.TextArea
        text_Resultado               matlab.ui.control.Label
        uitable1                     matlab.ui.control.Table
        uipanelTamanoMatrizCuadrada  matlab.ui.container.Panel
        pushbutton_Limpiar           matlab.ui.control.Button
        pushbutton_Mas               matlab.ui.control.Button
        pushbutton_Menos             matlab.ui.control.Button
        pushbutton_Regresar          matlab.ui.control.Button
    end

    methods (Access = private)
        function setGlobalCantColumnas(~, val) %permite setear variable global para almacenar la cantidad de columnas
            global CantColumnas
            CantColumnas = val;
        end
        
        function CantColumnasR = getGlobalCantColumnas(~) %permite obtener variable global de la cantidad de columnas
            global CantColumnas
            CantColumnasR = CantColumnas;
        end
        
        function setGlobalCantFilas(~,val) %permite setear variable global para almacenar la cantidad de filas
            global CantFilas
            CantFilas = val;
        end
        
        function CantFilasR = getGlobalCantFilas(~) %permite obtener variable global de la cantidad de filas
            global CantFilas
            CantFilasR = CantFilas;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function GUI_DETERMINANTE_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to GUI_DETERMINANTE (see VARARGIN)
            
            % Choose default command line output for GUI_DETERMINANTE
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
             
            filas=2;
            columnas=2;
            setGlobalCantFilas(app, filas);
            setGlobalCantColumnas(app, columnas);
            tamano=cell(filas,columnas);
            tamano(:,:)={'0'};
            set(handles.uitable_Matriz,'Data',tamano);
            set(handles.uitable_Matriz,'ColumnEditable',true(1,columnas));
        end

        % Button pushed function: pushbutton_Regresar
        function pushbutton_Regresar_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_Regresar (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            GUI_Menu_App
            closereq();
        end

        % Callback function
        function pushbutton_AceptarButtonPushed(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            filas=str2double(get(handles.fila,'String'));
            columnas=str2double(get(handles.colum,'String'));
            if(isnan(columnas) || isnan(filas))
                warndlg('El campo de filas y columnas no debe estar vacío, con letras o caracteres especiales','Precaución');
            else
                if(filas==0 || columnas==0)
                    warndlg('El campo de filas y columnas debe ser mayor a 0','Precaución');
                    set(handles.fila,'String','');
                    set(handles.colum,'String',''); 
                else
                    if(filas==columnas)
                        set(handles.pushbutton_Calcular,'visible','on');
                        tamano=cell(filas,columnas);
                        tamano(:,:)={'0'};
                        set(handles.uitable_Matriz,'Data',tamano);
                        set(handles.uitable_Matriz,'ColumnEditable',true(1,columnas));
                    else
                        set(handles.pushbutton_Calcular,'visible','off');
                        warndlg('La matriz no es cuadrada','Precaución');
                        set(handles.colum,'String','');
                        set(handles.fila,'String','');
                    end 
                end
            end       
        end

        % Button pushed function: pushbutton_Calcular
        function pushbutton_CalcularButtonPushed(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            valor=get(handles.uitable_Matriz,'data');
            valor=str2double(valor);

            determinanteR= determinante(valor);
            s=round(determinanteR);
            if(strcmp(num2str(s),'NaN'))
                warndlg('Debe ingresar valores para calcular el determinante','Precaución');
            else
                set(handles.textArea_Resultado,'string',num2str(s));
            end
            
        end

        % Button pushed function: pushbutton_Mas
        function pushbutton_MasButtonPushed(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            filas= getGlobalCantFilas(app)+1;
            columnas= getGlobalCantColumnas(app)+1;
            
            setGlobalCantFilas(app,filas);
            setGlobalCantColumnas(app,columnas);
            
            tamano=cell(filas,columnas);
            tamano(:,:)={'0'};
            set(handles.uitable_Matriz,'Data',tamano);
            set(handles.uitable_Matriz,'ColumnEditable',true(1,columnas));
            set(handles.textArea_Resultado,'string','');
        end

        % Button pushed function: pushbutton_Menos
        function pushbutton_MenosButtonPushed(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            filas= getGlobalCantFilas(app)-1;
            columnas= getGlobalCantColumnas(app)-1;
            
            if(columnas >= 2)
                setGlobalCantFilas(app,filas);
                setGlobalCantColumnas(app,columnas);
                
                tamano=cell(filas,columnas);
                tamano(:,:)={'0'};
                set(handles.uitable_Matriz,'Data',tamano);
                set(handles.uitable_Matriz,'ColumnEditable',true(1,columnas));
                set(handles.textArea_Resultado,'string','');
            end
        end

        % Button pushed function: pushbutton_Limpiar
        function pushbutton_LimpiarButtonPushed(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>        
            set(handles.textArea_Resultado,'string',''); 
            
            filas=2;
            columnas=2;
            setGlobalCantFilas(app,filas);
            setGlobalCantColumnas(app,columnas);
            
            tamano=cell(filas,columnas);
            tamano(:,:)={'0'};
            set(handles.uitable_Matriz,'Data',tamano);
            set(handles.uitable_Matriz,'ColumnEditable',true(1,columnas));

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'GUI_DETERMINANTE';
            app.figure1.Tag = 'figure1';
            app.panel = uipanel(app.figure1);
            app.panel.Title = 'Determinante de una matriz';
            app.pushbutton_Calcular = uibutton(app.panel, 'push');
            app.pushbutton_Calcular.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_CalcularButtonPushed, true);
            app.pushbutton_Calcular.Tag = 'pushbutton_Calcular';
            app.pushbutton_Calcular.Text = 'Calcular';
            app.TextArea = uitextarea(app.panel);
            app.TextArea.Tag = 'textArea_Resultado';
            app.TextArea.Editable = 'off';
            app.text_Resultado = uilabel(app.panel);
            app.text_Resultado.Tag = 'text_Resultado';
            app.text_Resultado.Text = 'Resultado';
            app.uitable1 = uitable(app.panel);
            app.uitable1.Tag = 'uitable_Matriz';
            app.uipanelTamanoMatrizCuadrada = uipanel(app.panel);
            app.uipanelTamanoMatrizCuadrada.Title = 'Definir tamaño de la matriz';
            app.uipanelTamanoMatrizCuadrada.Tag = 'uipanel_TamanoMatrizCuadrada';
            app.pushbutton_Limpiar = uibutton(app.uipanelTamanoMatrizCuadrada, 'push');
            app.pushbutton_Limpiar.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_LimpiarButtonPushed, true);
            app.pushbutton_Limpiar.Tag = 'pushbutton_Limpiar';
            app.pushbutton_Limpiar.Text = 'Limpiar';
            app.pushbutton_Mas = uibutton(app.uipanelTamanoMatrizCuadrada, 'push');
            app.pushbutton_Mas.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_MasButtonPushed, true);
            app.pushbutton_Mas.Tag = 'pushbutton_Mas';
            app.pushbutton_Mas.Text = '+';
            app.pushbutton_Menos = uibutton(app.uipanelTamanoMatrizCuadrada, 'push');
            app.pushbutton_Menos.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_MenosButtonPushed, true);
            app.pushbutton_Menos.Tag = 'pushbutton_Menos';
            app.pushbutton_Menos.Text = '-';
            app.pushbutton_Regresar = uibutton(app.figure1, 'push');
            app.pushbutton_Regresar.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_Regresar_Callback, true);
            app.pushbutton_Regresar.Tag = 'pushbutton_Regresar';
            app.pushbutton_Regresar.Text = 'Regresar';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_Det_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)GUI_DETERMINANTE_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end