classdef GUI_GAUSSJORDAN_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1             matlab.ui.Figure
        button_Regresar     matlab.ui.control.Button
        text2               matlab.ui.control.Label
        text3               matlab.ui.control.Label
        edit4               matlab.ui.control.EditField
        pushbutton_Aceptar  matlab.ui.control.Button
        pushbutton_Limpiar  matlab.ui.control.Button
        text_Resultado      matlab.ui.control.Label
        Resultado           matlab.ui.control.TextArea
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function GUI_GAUSSJORDAN_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to GUI_GAUSSJORDAN (see VARARGIN)
            
            % Choose default command line output for GUI_GAUSSJORDAN
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: button_Regresar
        function button_Regresar_Callback(app, event)
                 % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to button_Regresar (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            GUI_Menu_App
            closereq();
        end

        % Button pushed function: pushbutton_Aceptar
        function button_Aceptar_Callback(app, event)
            for n = 1:(length(x)-1)
                % Step 1: make the row N's Nth term 1 by dividing 
                % the whole row by it
                A = x(n,:);
                A = A/A(n);
                x(n,:) = A;
                
                % Step 2: for every other row add to it -1 * that rows Nth term *
                % the Nth row
                for k = 1:(length(x)-1)
                    if n~=k
                        x(k,:) = A*(-1*x(k,n)) + x(k,:);
                    end
                end
    end
    
    y = x(:,length(x))';
        end

        % Button pushed function: pushbutton_Limpiar
        function button_Limpiar_Callback(app, event)
            app.Resultado.Value = "";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'GUI_GAUSSJORDAN';
            app.figure1.Tag = 'figure1';
            app.button_Regresar = uibutton(app.figure1, 'push');
            app.button_Regresar.ButtonPushedFcn = createCallbackFcn(app, @button_Regresar_Callback, true);
            app.button_Regresar.Text = 'Regresar';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Gauss Jordan';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Ingrese la matriz de n x n columnas :';
            app.edit4 = uieditfield(app.figure1, 'text');
            app.edit4.Tag = 'edit4';
            app.pushbutton_Aceptar = uibutton(app.figure1, 'push');
            app.pushbutton_Aceptar.ButtonPushedFcn = createCallbackFcn(app, @button_Aceptar_Callback, true);
            app.pushbutton_Aceptar.Tag = 'pushbutton_Aceptar';
            app.pushbutton_Aceptar.Text = 'Aceptar';
            app.pushbutton_Limpiar = uibutton(app.figure1, 'push');
            app.pushbutton_Limpiar.ButtonPushedFcn = createCallbackFcn(app, @button_Limpiar_Callback, true);
            app.pushbutton_Limpiar.Tag = 'pushbutton_Limpiar';
            app.pushbutton_Limpiar.Text = 'Limpiar';
            app.text_Resultado = uilabel(app.figure1);
            app.text_Resultado.Tag = 'text_Resultado';
            app.text_Resultado.Text = 'Resultado';
            app.Resultado = uitextarea(app.figure1);
            app.Resultado.Editable = 'off';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_GAUSSJORDAN_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)GUI_GAUSSJORDAN_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end