classdef GUI_NEWTON_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                matlab.ui.Figure
        text2                  matlab.ui.control.Label
        text3                  matlab.ui.control.Label
        textTolerancia         matlab.ui.control.Label
        button_Regresar        matlab.ui.control.Button
        textValorInicial       matlab.ui.control.Label
        textIteraciones        matlab.ui.control.Label
        pushbuttonResultado    matlab.ui.control.Button
        pushbutton_Limpiar_2   matlab.ui.control.Button
        text_Resultado_2       matlab.ui.control.Label
        edit_tolerancia        matlab.ui.control.NumericEditField
        edit_valorInicial      matlab.ui.control.NumericEditField
        edit_iteraciones       matlab.ui.control.NumericEditField
        text_resultadoNumero   matlab.ui.control.Label
        edit_funcion           matlab.ui.control.EditField
        pushbuttonAceptar      matlab.ui.control.Button
        DatosDropDown          matlab.ui.control.DropDown
        QuedeseacalcularLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function GUI_NEWTON_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to GUI_NEWTON (see VARARGIN)
            
            % Choose default command line output for GUI_NEWTON
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: button_Regresar
        function button_Regresar_Callback(app, event)
             % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to button_Regresar (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            GUI_Menu_App
            closereq();
        end

        % Button pushed function: pushbutton_Limpiar_2
        function button_Limpiar_Callback(app, event)
            app.edit_funcion.Value = "";
            app.edit_tolerancia.Value = 0;
            app.edit_valorInicial.Value = 0;
            app.edit_iteraciones.Value = 0;
            app.text_resultadoNumero.Text = "";
            
        end

        % Button pushed function: pushbuttonAceptar
        function button_Aceptar_Callback(app, event)
            
            if(strcmp(app.DatosDropDown.Value,"Tolerancia"))
                set(app.textTolerancia,'visible','on');
                set(app.edit_tolerancia,'visible','on');
                 set(app.textIteraciones,'visible','off');
                set(app.edit_iteraciones,'visible','off');

            else
                set(app.textIteraciones,'visible','on');
                set(app.edit_iteraciones,'visible','on');
                set(app.textTolerancia,'visible','off');
                set(app.edit_tolerancia,'visible','off ');
              
            end
            
            
        end

        % Button pushed function: pushbuttonResultado
        function button_Resultado_Callback(app, event)
            cf=app.edit_funcion.Value;
            f = inline(cf);
            derivada = diff((str2sym(cf)));
            df = inline(derivada);
            tol = app.edit_tolerancia.Value;
            error = 100;
            x = app.edit_valorInicial.Value;
            n = 0;
            Iter=app.edit_iteraciones.Value;
            while (error > tol && Iter>n)    
                x = x - (f(x) / df (x));
                error = abs( f(x) );    
            end
            app.text_resultadoNumero.Text ="El error es: "+error;   
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'GUI_NEWTON';
            app.figure1.Tag = 'figure1';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Newton';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Ingrese la función de f (x) :';
            app.textTolerancia = uilabel(app.figure1);
            app.textTolerancia.Tag = 'text4';
            app.textTolerancia.Visible = 'off';
            app.textTolerancia.Text = 'Ingrese la tolerancia:';
            app.button_Regresar = uibutton(app.figure1, 'push');
            app.button_Regresar.ButtonPushedFcn = createCallbackFcn(app, @button_Regresar_Callback, true);
            app.button_Regresar.Tag = 'regresarbutton';
            app.button_Regresar.Text = 'Regresar';
            app.textValorInicial = uilabel(app.figure1);
            app.textValorInicial.Tag = 'text5';
            app.textValorInicial.Visible = 'off';
            app.textValorInicial.Text = 'Ingrese el valor inicial:';
            app.textIteraciones = uilabel(app.figure1);
            app.textIteraciones.Tag = 'text6';
            app.textIteraciones.Visible = 'off';
            app.textIteraciones.Text = 'Ingrese el número de iteraciones:';
            app.pushbuttonResultado = uibutton(app.figure1, 'push');
            app.pushbuttonResultado.ButtonPushedFcn = createCallbackFcn(app, @button_Resultado_Callback, true);
            app.pushbuttonResultado.Tag = 'pushbutton_Aceptar';
            app.pushbuttonResultado.Text = 'Resultado';
            app.pushbutton_Limpiar_2 = uibutton(app.figure1, 'push');
            app.pushbutton_Limpiar_2.ButtonPushedFcn = createCallbackFcn(app, @button_Limpiar_Callback, true);
            app.pushbutton_Limpiar_2.Tag = 'pushbutton_Limpiar';
            app.pushbutton_Limpiar_2.Text = 'Limpiar';
            app.text_Resultado_2 = uilabel(app.figure1);
            app.text_Resultado_2.Tag = 'text_Resultado';
            app.text_Resultado_2.Text = 'Resultado:';
            app.edit_tolerancia = uieditfield(app.figure1, 'numeric');
            app.edit_tolerancia.Visible = 'off';
            app.edit_valorInicial = uieditfield(app.figure1, 'numeric');
            app.edit_valorInicial.Visible = 'off';
            app.edit_iteraciones = uieditfield(app.figure1, 'numeric');
            app.edit_iteraciones.Visible = 'off';
            app.text_resultadoNumero = uilabel(app.figure1);
            app.text_resultadoNumero.Tag = 'text_Resultado';
            app.text_resultadoNumero.Text = '';
            app.edit_funcion = uieditfield(app.figure1, 'text');
            app.pushbuttonAceptar = uibutton(app.figure1, 'push');
            app.pushbuttonAceptar.ButtonPushedFcn = createCallbackFcn(app, @button_Aceptar_Callback, true);
            app.pushbuttonAceptar.Text = 'Aceptar';
            app.DatosDropDown = uidropdown(app.figure1);
            app.DatosDropDown.Items = {'Tolerancia', 'Numero de iteraciones'};
            app.DatosDropDown.Value = 'Tolerancia';
            app.QuedeseacalcularLabel = uilabel(app.figure1);
            app.QuedeseacalcularLabel.Text = '¿Que desea calcular?';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_NEWTON_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)GUI_NEWTON_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end