classdef GUI_TRAZADORCUBICO < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RegresarButton                  matlab.ui.control.Button
        CantidaddepuntosEditFieldLabel  matlab.ui.control.Label
        CantidaddepuntosEditField       matlab.ui.control.NumericEditField
        GenerarButton                   matlab.ui.control.Button
        AceptarButton                   matlab.ui.control.Button
        QueDatostieneDropDownLabel      matlab.ui.control.Label
        QueDatostieneDropDown           matlab.ui.control.DropDown
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RegresarButton
        function button_Regresar_Callback(app, event)
             % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to button_Regresar (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            GUI_Menu_App
            closereq();
        end

        % Button pushed function: AceptarButton
        function button_Aceptar_Callback(app, event)
        x = [2 4 5 8 10];
        y = [5 6 9 5 4];
        u = 1;
        v = 0;
        z = [2:0.01:10];
        %u,v son las pendientes de los extremos 
        n = length(x);
        clear A B C D;
        if n <4
           return
        end
        for i= 1:n-1
            h(i)= x(i+1)-x(i);
        end
        
        B(1) = -2*h(1)/6;
        C(1) = -h(1)/6;
        D(1) = u-(y(2)-y(1))/h(1);
        for i=2:n-1
            A(i)= h(i-1);
            B(i)= 2*(h(i-1)+h(i));
            C(i)= h(i);
            D(i)= 6*((y(i+1)-y(i))/h(i)-(y(i)-y(i-1))/h(i-1));
        end
        A(n) = h(n-1)/6;
        B(n) = h(n-1)/3;
        D(n) = v-(y(n)-y(n-1))/h(n-1);
        
        for i = 1:n-1
            a(i) = (A:B:D(i+1)-A:B:D(i))/(6*h(i));
            b(i) = A:B:D(i)/2;
            c(i) = (y(i+1)-y(i))/h(i)-(2*h(i)*s(i)+h(i)*s(i+1))/6;
            d(i) = y(i);
        end
        if nargin==5    
          p=[];
          m=length(z);
          for k=1:m
              t=z(k);
              for i=1:n-1
                 if t>=x(i) & t <=x(i+1)
                     p(k) = a(i)*(t-x(i))^3+b(i)*(t-x(i))^2+c(i)*(t-x(i))+d(i);
                 end
              end
          end
          if m>1
              k = m; i =n-1;
                     p(k) = a(i)*(t-x(i))^3+b(i)*(t-x(i))^2+c(i)*(t-x(i))+d(i);
          end
          clear a b c d;
          a = p;
        end    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RegresarButton = uibutton(app.UIFigure, 'push');
            app.RegresarButton.ButtonPushedFcn = createCallbackFcn(app, @button_Regresar_Callback, true);
            app.RegresarButton.Text = 'Regresar';
            app.CantidaddepuntosEditFieldLabel = uilabel(app.UIFigure);
            app.CantidaddepuntosEditFieldLabel.Text = 'Cantidad de puntos';
            app.CantidaddepuntosEditField = uieditfield(app.UIFigure, 'numeric');
            app.GenerarButton = uibutton(app.UIFigure, 'push');
            app.GenerarButton.Text = 'Generar';
            app.AceptarButton = uibutton(app.UIFigure, 'push');
            app.AceptarButton.ButtonPushedFcn = createCallbackFcn(app, @button_Aceptar_Callback, true);
            app.AceptarButton.Text = 'Aceptar';
            app.QueDatostieneDropDownLabel = uilabel(app.UIFigure);
            app.QueDatostieneDropDownLabel.Text = '¿Que Datos tiene?';
            app.QueDatostieneDropDown = uidropdown(app.UIFigure);
            app.QueDatostieneDropDown.Items = {'Imagenes', 'Función'};
            app.QueDatostieneDropDown.Value = 'Imagenes';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_TRAZADORCUBICO

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end